classdef MainGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WatermarkUIFigure               matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AboutTab                        matlab.ui.container.Tab
        Button                          matlab.ui.control.Button
        InformationTextAreaLabel        matlab.ui.control.Label
        InformationTextArea             matlab.ui.control.TextArea
        Button_2                        matlab.ui.control.Button
        HowtouseTextAreaLabel           matlab.ui.control.Label
        HowtouseTextArea                matlab.ui.control.TextArea
        WatermarkingusingWalshHadamardTransformLabel  matlab.ui.control.Label
        Button_3                        matlab.ui.control.Button
        ContactTextAreaLabel            matlab.ui.control.Label
        ContactTextArea                 matlab.ui.control.TextArea
        KeyGeneratorTab                 matlab.ui.container.Tab
        KeyGeneratorLabel               matlab.ui.control.Label
        DisplayPanel_4                  matlab.ui.container.Panel
        UIAxes_13                       matlab.ui.control.UIAxes
        UIAxes_14                       matlab.ui.control.UIAxes
        ActionPanel_3                   matlab.ui.container.Panel
        ChooseWatermarkImageButton_3    matlab.ui.control.Button
        GenerateKeyButton               matlab.ui.control.Button
        SaveKeyButton                   matlab.ui.control.Button
        EmbedWatermarkTab               matlab.ui.container.Tab
        EmbedWatermarkLabel             matlab.ui.control.Label
        DisplayPanel                    matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        Label_9                         matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        ActionPanel                     matlab.ui.container.Panel
        ChooseHostImageButton           matlab.ui.control.Button
        ChooseWatermarkImageButton      matlab.ui.control.Button
        ChooseKeyButton                 matlab.ui.control.Button
        EmbedWatermarkButton            matlab.ui.control.Button
        SaveWatermarkedImageButton      matlab.ui.control.Button
        ComparisonPanel                 matlab.ui.container.Panel
        PSNRButton                      matlab.ui.control.Button
        WPSNRButton                     matlab.ui.control.Button
        SSIMButton                      matlab.ui.control.Button
        NCButton                        matlab.ui.control.Button
        VisualButton                    matlab.ui.control.Button
        HistogramButton                 matlab.ui.control.Button
        ExtractWatermarkTab             matlab.ui.container.Tab
        ExtractWatermarkLabel           matlab.ui.control.Label
        DisplayPanel_2                  matlab.ui.container.Panel
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        Label_11                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        ActionPanel_2                   matlab.ui.container.Panel
        ChooseWatermarkedImageButton    matlab.ui.control.Button
        ChooseKeyButton_2               matlab.ui.control.Button
        ExtractWatermarkButton          matlab.ui.control.Button
        SaveExtractedWatermarkImageButton  matlab.ui.control.Button
        ChooseOriginalWatermarkImageButton  matlab.ui.control.Button
        ComparisonPanel_2               matlab.ui.container.Panel
        PSNRButton_2                    matlab.ui.control.Button
        WPSNRButton_2                   matlab.ui.control.Button
        SSIMButton_2                    matlab.ui.control.Button
        NCButton_2                      matlab.ui.control.Button
        VisualButton_2                  matlab.ui.control.Button
        HistogramButton_2               matlab.ui.control.Button
        AttackandExtractTab             matlab.ui.container.Tab
        AttackandExtractLabel           matlab.ui.control.Label
        DisplayPanel_3                  matlab.ui.container.Panel
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_10                       matlab.ui.control.UIAxes
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        UIAxes_11                       matlab.ui.control.UIAxes
        UIAxes_12                       matlab.ui.control.UIAxes
        Label_7                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        ListBoxLabel                    matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        ChooseWatermarkedImageButton_2  matlab.ui.control.Button
        ChooseWatermarkImageToCompareButton  matlab.ui.control.Button
        ComparisonTab                   matlab.ui.container.Tab
    end


    properties (Access = public)
        % Global variables (Bien toan cuc)
        WatermarkImageFullName % Path of WatermarkImage
        WatermarkImage % Matrices of WatermarkImage
        KeyFullName % Key Path
        Key % Key matrices
        HostImageFullName % Path of Host Image
        HostImage % Host Image matrices
        WatermarkedImage % Watermarked Image
        WatermarkedImageFullName % Watermarked Image path
        ExtractedWatermarkImageFullName % Extracted Watermark Image Path
        ExtractedWatermarkImage % Extracted Watermark Image matrices
    end
% 　    /O/ /O/
% 　   /､/ /､/
%  ―丶/_/_/_/丶
%  |　 |┃　 ┃|
%  |___|.-*-.|　
%  | |つ￣￣|つ
% ∝| |＿＿＿|
%     連装砲


    methods (Access = private)

        % Button pushed function: ChooseWatermarkImageButton_3
        function ChooseWatermarkImageButton_3Pushed(app, event)
            % Choose Watermark Button of Key Generator Tab
            [filename,filepath] = uigetfile({'*.jpg;*.png;*.bmp;*.oct'}, 'Select Watermark Image to Open'); % Open windows to select image
            app.WatermarkImageFullName = [filepath, filename]; % Watermark image path
            if(isequal(filename,0) ) % phai chon anh
                f = errordlg('Must pick a image');
            else
                app.WatermarkImage = imread(app.WatermarkImageFullName); % Read Watermark Image and save to public properties
                I = imshow(app.WatermarkImage, 'Parent', app.UIAxes_13); % Show Watermark Image to UIAxes_13   
                % Set limits of axes
                app.UIAxes_13.XLim = [0 I.XData(2)];
                app.UIAxes_13.YLim = [0 I.YData(2)];
                app.GenerateKeyButton.Enable = 'on';
            end
        end

        % Button pushed function: GenerateKeyButton
        function GenerateKeyButtonPushed(app, event)
            % Generate Key Button of Key Generator Tab
            try
                % Get size of Watermark Image
                [WatermarkImageRows, WatermarkImageColumns, WatermarkImageNumberOfColorBands] = size(app.WatermarkImage);
                % Generate key by scrambling all the pixels and save the
                % scarmble order to variable key
                app.Key = randperm(WatermarkImageRows*WatermarkImageColumns);
                % Show the scrambled watermark image
                ScrambledImage = scrambledImage(app.WatermarkImage,app.Key); % scramble watermark image
                I = imshow(ScrambledImage, 'Parent', app.UIAxes_14); % Show Scrambled Watermark Image to UIAxes_14   
                % Set limits of axes
                app.UIAxes_14.XLim = [0 I.XData(2)];
                app.UIAxes_14.YLim = [0 I.YData(2)];
                % Enable Save Key Button
                app.SaveKeyButton.Enable = 'on';             
            catch ME               
                msgbox(ME.message,'ERROR!!!','error');  % Bat loi (Catch handle exceptions)
            end
        end

        % Button pushed function: SaveKeyButton
        function SaveKeyButtonPushed(app, event)
            % Save Key Button of Key Generator Tab
            try
                [filename,pathname]=uiputfile({'*.txt;*.mat'},'Save Key','./MyKey.txt'); % Open window to save file
                var = strcat(pathname,filename); % store file path to variable "var"
                Key = app.Key; % Save function not allow to use global variable so we create "Key" to store "app.Key"
                save(var, 'Key', '-ascii', '-tabs'); % Save key
            catch ME
                msgbox(ME.message,'ERROR!!!','error');  % Bat loi (Catch handle exceptions)
            end           
        end

        % Button pushed function: ChooseHostImageButton
        function ChooseHostImageButtonPushed(app, event)
            % Remove title, axis labels, and tick labels
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            [filename,filepath] = uigetfile({'*.jpg;*.png;*.bmp;*.oct'}, 'Select Host Image to Open');
            app.HostImageFullName = [filepath, filename];  
            if(isequal(filename,0) ) % phai chon anh
                f = errordlg('Must pick a file');
            else
                app.HostImage = imread(app.HostImageFullName); %host image
                I = imshow(app.HostImage, 'Parent', app.UIAxes);   
                app.ChooseWatermarkImageButton.Enable = 'on';
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];               
            end
        end

        % Button pushed function: ChooseWatermarkImageButton
        function ChooseWatermarkImageButtonPushed(app, event)
            % Remove title, axis labels, and tick labels
            xlabel(app.UIAxes_2, []);
            ylabel(app.UIAxes_2, []);
            app.UIAxes_2.XAxis.TickLabels = {};
            app.UIAxes_2.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            [filename,filepath] = uigetfile({'*.jpg;*.png;*.bmp;*.oct'}, 'Select Watermark Image to Open');
            app.WatermarkImageFullName = [filepath, filename]; % Watermark image path
    
            if(isequal(filename,0) ) % phai chon anh
                f = errordlg('Must pick a file ');
            else
                if (checkWatermarkSize(app.HostImageFullName,app.WatermarkImageFullName) == false)
                    f = errordlg('Size too large');
                else
                    app.WatermarkImage = imread(app.WatermarkImageFullName);
                    I=imshow(app.WatermarkImage, 'Parent', app.UIAxes_2); 
                    app.ChooseKeyButton.Enable = 'on';
                    % Set limits of axes
                    app.UIAxes_2.XLim = [0 I.XData(2)];
                    app.UIAxes_2.YLim = [0 I.YData(2)];                    
                end
            end    
        end

        % Button pushed function: ChooseKeyButton
        function ChooseKeyButtonPushed(app, event)
            % Open window to select key file
            [filename,filepath] = uigetfile({'*.txt;*.mat;'}, 'Select key to Open');
            if(isequal(filename,0) ) % phai chon anh
                 f = errordlg('Must pick a file');
            else
                app.KeyFullName = [filepath, filename];
                %if ((isequal(app.fullname_2,0)==0) && (isequal(app.fullname,0)==0) && (isequal(app.fullname_3,0)==0))
                app.EmbedWatermarkButton.Enable='on';
                %end
            end
        end

        % Button pushed function: EmbedWatermarkButton
        function EmbedWatermarkButtonPushed(app, event)
            try
                d = uiprogressdlg(app.WatermarkUIFigure,'Title','Please Wait (｀・ω・´)',...
                'Message','Embedding watermark...','Indeterminate','on');
                check = true;
                while(check)
                    d.Message = 'Embedding watermark...( ﾉｼ・ヮ・)ﾉｼ三( ﾉｼ・ヮ・)ﾉｼ';    
                    app.WatermarkedImage=Embed(app.WatermarkImageFullName,app.HostImageFullName,app.KeyFullName);         
                    check=false;
                end
                % Show title
                title(app.UIAxes, "Host Image");
                title(app.UIAxes_2, "Watermarked Image");
                % Show image
                I=imshow(app.HostImage, 'Parent', app.UIAxes); 
                I_2=imshow(app.WatermarkedImage, 'Parent', app.UIAxes_2); 
                % gioi han khung anh Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                app.UIAxes_2.XLim = [0 I_2.XData(2)];
                app.UIAxes_2.YLim = [0 I_2.YData(2)];
                % Show save button
                app.SaveWatermarkedImageButton.Enable = 'on';
            catch ME
                msgbox(ME.message,'ERROR!!!','error');  % Bat loi (Catch handle exceptions)
            end
        end

        % Button pushed function: SaveWatermarkedImageButton
        function SaveWatermarkedImageButtonPushed(app, event)
            try
                [filename,pathname]=uiputfile({'*.jpg;*.jpeg;*.gif;*.tif;*.tiff;*.rgb;*.bmp;*.png',...
                'All support image files';'*.jpg;*.jpeg','JPEG files(*.jpg;*.jpeg)';'*.gif','GIF files (*.gif)';...
                '*.tif;*.tiff','TIFF files (*.tif;*.tiff)';'*.bmp','Bitmap files (*.bmp)';'*.png','PNG files (*.png)'},'Save Watermarked Image');
                var = strcat(pathname,filename);
                imwrite(uint8(app.WatermarkedImage),var);
                app.WatermarkedImageFullName = var;
                
                %show button of next process
                app.PSNRButton.Enable = 'on';
                app.WPSNRButton.Enable = 'on';
                app.SSIMButton.Enable = 'on';
                app.NCButton.Enable = 'on';
                app.VisualButton.Enable = 'on';
                app.HistogramButton.Enable = 'on';
            catch ME
                msgbox(ME.message,'ERROR!!!','error');  % Bat loi (Catch handle exceptions)
            end           
        end

        % Button pushed function: PSNRButton
        function PSNRButtonPushed(app, event)
            % Set visible and text of Label_9
            app.Label_9.Visible = 'on';
            app.Label_9.Text = 'PSNR';
            % Set visible and text of Label_10
            % Label 10 text calculate psnr of 2 image
            app.Label_10.Visible = 'on';
            app.Label_10.Text = string(psnr(imread(app.HostImageFullName), imread(app.WatermarkedImageFullName)));
        end

        % Button pushed function: WPSNRButton
        function WPSNRButtonPushed(app, event)
            % Add path of subfolder
            addpath(genpath(pwd)); 
            % Imread 2 images
            a=double(imread(app.HostImageFullName));
            b=double(imread(app.WatermarkedImageFullName));
            % Calculate WPSNR with custom function
            [psnr,wpsnr1]=wpsnr(a,b);
            % Set text
            app.Label_9.Visible = 'on';
            app.Label_9.Text = 'WPSNR';
            app.Label_10.Visible = 'on';
            app.Label_10.Text = string(wpsnr1);
        end

        % Button pushed function: SSIMButton
        function SSIMButtonPushed(app, event)
            app.Label_9.Visible = 'on';
            app.Label_9.Text = 'SSIM';
            app.Label_10.Visible = 'on';
            app.Label_10.Text = string(ssim(imread(app.WatermarkedImageFullName), imread(app.HostImageFullName)));
        end

        % Button pushed function: NCButton
        function NCButtonPushed(app, event)
            % Add path of subfolder
            addpath(genpath(pwd));
            % Calculate NC with custom function and set text
            app.Label_9.Visible = 'on';
            app.Label_9.Text = 'NC';
            app.Label_10.Visible = 'on';
            app.Label_10.Text = string(nc(app.WatermarkedImageFullName,app.HostImageFullName));
        end

        % Button pushed function: VisualButton
        function VisualButtonPushed(app, event)
            % Show anh
            I = imshow(app.HostImage, 'Parent', app.UIAxes); 
            I_2 = imshow(app.WatermarkedImage, 'Parent', app.UIAxes_2); 
            % gioi han khung anh
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            app.UIAxes_2.XLim = [0 I_2.XData(2)];
            app.UIAxes_2.YLim = [0 I_2.YData(2)];
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            % Imread 2 images
            a = imread(app.HostImageFullName);
            b = imread(app.WatermarkedImageFullName);      
            % Clear Axes
            cla(app.UIAxes,'reset');
            cla(app.UIAxes_2,'reset');
            % Show histogram of 2 images
            I = histogram(app.UIAxes, a); 
            I_2 = histogram(app.UIAxes_2, b); 
        end

        % Button pushed function: ChooseWatermarkedImageButton
        function ChooseWatermarkedImageButtonPushed(app, event)
            % Remove title, axis labels, and tick labels
            xlabel(app.UIAxes_3, []);
            ylabel(app.UIAxes_3, []);
            app.UIAxes_3.XAxis.TickLabels = {};
            app.UIAxes_3.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            [filename,filepath] = uigetfile({'*.jpg;*.png;*.bmp;*.oct'}, 'Select Watermarked Image to Open');
            app.WatermarkedImageFullName = [filepath, filename]; % Watermark image path
            if(isequal(filename,0)) % phai chon anh
                f = errordlg('Must pick a file ');
            else
                app.WatermarkedImage = imread(app.WatermarkedImageFullName);
                I = imshow(app.WatermarkedImage, 'Parent', app.UIAxes_3); 
                app.ChooseKeyButton_2.Enable = 'on';
                % Set limits of axes
                app.UIAxes_3.XLim = [0 I.XData(2)];
                app.UIAxes_3.YLim = [0 I.YData(2)];                    
            end    
        end

        % Button pushed function: ChooseKeyButton_2
        function ChooseKeyButton_2Pushed(app, event)
            % Open window to select key file
            [filename,filepath] = uigetfile({'*.txt;*.mat;'}, 'Select key to Open');
            if(isequal(filename,0)) % phai chon anh
                 f = errordlg('Must pick a file');
            else
                app.KeyFullName = [filepath, filename];
                %if ((isequal(app.fullname_2,0)==0) && (isequal(app.fullname,0)==0) && (isequal(app.fullname_3,0)==0))
                app.ExtractWatermarkButton.Enable='on';
                %end
            end
        end

        % Button pushed function: ExtractWatermarkButton
        function ExtractWatermarkButtonPushed(app, event)
             try
                d = uiprogressdlg(app.WatermarkUIFigure,'Title','Please Wait (｀・ω・´)',...
                'Message','Extracting watermark...','Indeterminate','on');
                check = true;
                while(check)
                    d.Message = 'Extracting watermark...( ﾉｼ・ヮ・)ﾉｼ三( ﾉｼ・ヮ・)ﾉｼ';    
                    app.ExtractedWatermarkImage=extract(app.WatermarkedImageFullName, app.KeyFullName);         
                    check = false;
                end
                % Show title
                title(app.UIAxes_3, "Watermarked Image");
                title(app.UIAxes_4, "Extracted Watermark Image");
                % Show image
                I = imshow(app.WatermarkedImage, 'Parent', app.UIAxes_3); 
                I_2 = imshow(app.ExtractedWatermarkImage, 'Parent', app.UIAxes_4); 
                % gioi han khung anh Set limits of axes
                app.UIAxes_3.XLim = [0 I.XData(2)];
                app.UIAxes_3.YLim = [0 I.YData(2)];
                app.UIAxes_4.XLim = [0 I_2.XData(2)];
                app.UIAxes_4.YLim = [0 I_2.YData(2)];
                % Show save button
                app.SaveExtractedWatermarkImageButton.Enable = 'on';
            catch ME
                msgbox(ME.message,'ERROR!!!','error');  % Bat loi (Catch handle exceptions)
            end
        end

        % Button pushed function: SaveExtractedWatermarkImageButton
        function SaveExtractedWatermarkImageButtonPushed(app, event)
            try
                [filename,pathname] = uiputfile({'*.jpg;*.jpeg;*.gif;*.tif;*.tiff;*.rgb;*.bmp;*.png',...
                'All support image files';'*.jpg;*.jpeg','JPEG files(*.jpg;*.jpeg)';'*.gif','GIF files (*.gif)';...
                '*.tif;*.tiff','TIFF files (*.tif;*.tiff)';'*.bmp','Bitmap files (*.bmp)';'*.png','PNG files (*.png)'},'Save Watermarked Image');
                var = strcat(pathname, filename);
                imwrite(uint8(app.ExtractedWatermarkImage), var);
                app.ExtractedWatermarkImageFullName = var;              
                %show button of next process
                app.ChooseOriginalWatermarkImageButton.Enable = 'on';                
            catch ME
                msgbox(ME.message,'ERROR!!!','error');  % Bat loi (Catch handle exceptions)
            end           
        end

        % Button pushed function: ChooseOriginalWatermarkImageButton
        function ChooseOriginalWatermarkImageButtonPushed(app, event)
            % Remove title, axis labels, and tick labels
            xlabel(app.UIAxes_3, []);
            ylabel(app.UIAxes_3, []);
            app.UIAxes_3.XAxis.TickLabels = {};
            app.UIAxes_3.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            [filename,filepath] = uigetfile({'*.jpg;*.png;*.bmp;*.oct'}, 'Select Watermark Image to Open');
            app.WatermarkImageFullName = [filepath, filename]; % Watermark image path
            if(isequal(filename,0)) % phai chon anh
                f = errordlg('Must pick a file ');
            else      
                app.WatermarkImage = imread(app.WatermarkImageFullName);
                I = imshow(app.WatermarkImage, 'Parent', app.UIAxes_3); 
                title(app.UIAxes_3, "Original Watermark Image");
                % Set limits of axes
                app.UIAxes_3.XLim = [0 I.XData(2)];
                app.UIAxes_3.YLim = [0 I.YData(2)];  
                % Enable comparison button
                app.PSNRButton_2.Enable = 'on';
                app.WPSNRButton_2.Enable = 'on';
                app.SSIMButton_2.Enable = 'on';
                app.NCButton_2.Enable = 'on';
                app.VisualButton_2.Enable = 'on';
                app.HistogramButton_2.Enable = 'on';
            end    
        end

        % Button pushed function: PSNRButton_2
        function PSNRButton_2Pushed(app, event)
             % Set visible and text of Label_9
            app.Label_11.Visible = 'on';
            app.Label_11.Text = 'PSNR';
            % Set visible and text of Label_10
            % Label 10 text calculate psnr of 2 image
            app.Label_12.Visible = 'on';
            app.Label_12.Text = string(psnr(imread(app.WatermarkImageFullName), imread(app.ExtractedWatermarkImageFullName)));
        end

        % Button pushed function: WPSNRButton_2
        function WPSNRButton_2Pushed(app, event)
            % Add path of subfolder
            addpath(genpath(pwd)); 
            % Imread 2 images
            a=double(imread(app.WatermarkImageFullName));
            b=double(imread(app.ExtractedWatermarkImageFullName));
            % Calculate WPSNR with custom function
            [psnr,wpsnr1]=wpsnr(a,b);
            % Set text
            app.Label_11.Visible = 'on';
            app.Label_11.Text = 'WPSNR';
            app.Label_12.Visible = 'on';
            app.Label_12.Text = string(wpsnr1);
        end

        % Button pushed function: SSIMButton_2
        function SSIMButton_2Pushed(app, event)
            app.Label_11.Visible = 'on';
            app.Label_11.Text = 'SSIM';
            app.Label_12.Visible = 'on';
            app.Label_12.Text = string(ssim(imread(app.ExtractedWatermarkImageFullName), imread(app.WatermarkImageFullName)));
        end

        % Button pushed function: NCButton_2
        function NCButton_2Pushed(app, event)
            % Add path of subfolder
            addpath(genpath(pwd));
            % Calculate NC with custom function and set text
            app.Label_11.Visible = 'on';
            app.Label_11.Text = 'NC';
            app.Label_12.Visible = 'on';
            app.Label_12.Text = string(nc(app.ExtractedWatermarkImageFullName,app.WatermarkImageFullName));
        end

        % Button pushed function: VisualButton_2
        function VisualButton_2Pushed(app, event)
            % Show anh
            I = imshow(app.WatermarkImage, 'Parent', app.UIAxes_3); 
            I_2 = imshow(app.ExtractedWatermarkImage, 'Parent', app.UIAxes_4); 
            % gioi han khung anh
            app.UIAxes_3.XLim = [0 I.XData(2)];
            app.UIAxes_3.YLim = [0 I.YData(2)];
            app.UIAxes_4.XLim = [0 I_2.XData(2)];
            app.UIAxes_4.YLim = [0 I_2.YData(2)];
        end

        % Button pushed function: HistogramButton_2
        function HistogramButton_2Pushed(app, event)
            % Read 2 images
            a = imread(app.WatermarkImageFullName);
            b = imread(app.ExtractedWatermarkImageFullName);      
            % Clear Axes
            cla(app.UIAxes_3,'reset');
            cla(app.UIAxes_4,'reset');
            % Show histogram of 2 images
            I = histogram(app.UIAxes_3, a); 
            I_2 = histogram(app.UIAxes_4, b); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WatermarkUIFigure = uifigure;
            app.WatermarkUIFigure.Name = 'Watermark';
            app.TabGroup = uitabgroup(app.WatermarkUIFigure);
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Button = uibutton(app.AboutTab, 'push');
            app.Button.Icon = 'apple_watermarked.png';
            app.Button.Text = '';
            app.InformationTextAreaLabel = uilabel(app.AboutTab);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.AboutTab);
            app.InformationTextArea.Editable = 'off';
            app.InformationTextArea.Value = {'Project: Researching a robust and imperceptible blind color image watermarking using Walsh Hadamard Transform'; 'Author: Tran Hai Dang - AT120515 - Academy of Cryptography Techniques'; 'Instructor: Tran Thi Xuyen - Academy of Cryptography Techniques'; 'Reference: An effective robust and imperceptible blind color image watermarking using WHT - K. Prabha, I. Shatheesh Sam '; ''};
            app.Button_2 = uibutton(app.AboutTab, 'push');
            app.Button_2.Icon = 'apple_watermarked.png';
            app.Button_2.Text = '';
            app.HowtouseTextAreaLabel = uilabel(app.AboutTab);
            app.HowtouseTextAreaLabel.Text = {'How'; 'to '; 'use'};
            app.HowtouseTextArea = uitextarea(app.AboutTab);
            app.HowtouseTextArea.Editable = 'off';
            app.HowtouseTextArea.Value = {'######## Embed Process #########'; '1/ Choose Host Image'; '2/ Choose Watermark Image (Caution: Embed capacity of this method is 0.25bpp so you must choose wisely)'; '3/ Choose key'; '4/ Click "Embed" button'; '5/ Click "Save" button to save Watermarked Image'; '6/ You can compare the difference between Host Image and Watermarked Image by choose comparison button in "comparison group"'; ''; '######## Extract Process ########'; 'a'; 'a'; 'a'};
            app.WatermarkingusingWalshHadamardTransformLabel = uilabel(app.AboutTab);
            app.WatermarkingusingWalshHadamardTransformLabel.Text = 'Watermarking using Walsh Hadamard Transform';
            app.Button_3 = uibutton(app.AboutTab, 'push');
            app.Button_3.Icon = 'hvmm.png';
            app.Button_3.Text = '';
            app.ContactTextAreaLabel = uilabel(app.AboutTab);
            app.ContactTextAreaLabel.Text = 'Contact';
            app.ContactTextArea = uitextarea(app.AboutTab);
            app.ContactTextArea.Editable = 'off';
            app.ContactTextArea.Value = {'Github: haidang10897'; 'Facebook: tran.h.dang.54'; 'Twitter: haidang10897'; 'Steam: BoDut'; 'Gmail: haidangtran97@gmail.com'; 'Outlook: AT120515@actvn.edu.vn'};
            app.KeyGeneratorTab = uitab(app.TabGroup);
            app.KeyGeneratorTab.Title = 'Key Generator';
            app.KeyGeneratorLabel = uilabel(app.KeyGeneratorTab);
            app.KeyGeneratorLabel.Text = 'Key Generator';
            app.DisplayPanel_4 = uipanel(app.KeyGeneratorTab);
            app.DisplayPanel_4.Title = 'Display';
            app.UIAxes_13 = uiaxes(app.DisplayPanel_4);
            title(app.UIAxes_13, 'Original Watermark Image')
            app.UIAxes_13.XTick = [];
            app.UIAxes_13.YTick = [];
            app.UIAxes_14 = uiaxes(app.DisplayPanel_4);
            title(app.UIAxes_14, 'Scrambled Watermark Image')
            app.UIAxes_14.XTick = [];
            app.UIAxes_14.YTick = [];
            app.ActionPanel_3 = uipanel(app.KeyGeneratorTab);
            app.ActionPanel_3.Title = 'Action';
            app.ChooseWatermarkImageButton_3 = uibutton(app.ActionPanel_3, 'push');
            app.ChooseWatermarkImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @ChooseWatermarkImageButton_3Pushed, true);
            app.ChooseWatermarkImageButton_3.Text = {'Choose Watermark '; 'Image'};
            app.GenerateKeyButton = uibutton(app.ActionPanel_3, 'push');
            app.GenerateKeyButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateKeyButtonPushed, true);
            app.GenerateKeyButton.Enable = 'off';
            app.GenerateKeyButton.Text = 'Generate Key';
            app.SaveKeyButton = uibutton(app.ActionPanel_3, 'push');
            app.SaveKeyButton.ButtonPushedFcn = createCallbackFcn(app, @SaveKeyButtonPushed, true);
            app.SaveKeyButton.Enable = 'off';
            app.SaveKeyButton.Text = 'Save Key';
            app.EmbedWatermarkTab = uitab(app.TabGroup);
            app.EmbedWatermarkTab.Title = 'Embed Watermark';
            app.EmbedWatermarkLabel = uilabel(app.EmbedWatermarkTab);
            app.EmbedWatermarkLabel.Text = 'Embed Watermark';
            app.DisplayPanel = uipanel(app.EmbedWatermarkTab);
            app.DisplayPanel.Title = 'Display';
            app.UIAxes = uiaxes(app.DisplayPanel);
            title(app.UIAxes, 'Host Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.DisplayPanel);
            title(app.UIAxes_2, 'Watermark Image')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.Label_9 = uilabel(app.DisplayPanel);
            app.Label_9.Visible = 'off';
            app.Label_10 = uilabel(app.DisplayPanel);
            app.Label_10.Visible = 'off';
            app.ActionPanel = uipanel(app.EmbedWatermarkTab);
            app.ActionPanel.Title = 'Action';
            app.ChooseHostImageButton = uibutton(app.ActionPanel, 'push');
            app.ChooseHostImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseHostImageButtonPushed, true);
            app.ChooseHostImageButton.Text = 'Choose Host Image';
            app.ChooseWatermarkImageButton = uibutton(app.ActionPanel, 'push');
            app.ChooseWatermarkImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseWatermarkImageButtonPushed, true);
            app.ChooseWatermarkImageButton.Enable = 'off';
            app.ChooseWatermarkImageButton.Text = {'Choose Watermark '; 'Image'};
            app.ChooseKeyButton = uibutton(app.ActionPanel, 'push');
            app.ChooseKeyButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseKeyButtonPushed, true);
            app.ChooseKeyButton.Enable = 'off';
            app.ChooseKeyButton.Text = 'Choose Key';
            app.EmbedWatermarkButton = uibutton(app.ActionPanel, 'push');
            app.EmbedWatermarkButton.ButtonPushedFcn = createCallbackFcn(app, @EmbedWatermarkButtonPushed, true);
            app.EmbedWatermarkButton.Enable = 'off';
            app.EmbedWatermarkButton.Text = 'Embed Watermark';
            app.SaveWatermarkedImageButton = uibutton(app.ActionPanel, 'push');
            app.SaveWatermarkedImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveWatermarkedImageButtonPushed, true);
            app.SaveWatermarkedImageButton.Enable = 'off';
            app.SaveWatermarkedImageButton.Text = {'Save Watermarked '; 'Image'};
            app.ComparisonPanel = uipanel(app.EmbedWatermarkTab);
            app.ComparisonPanel.Title = 'Comparison';
            app.PSNRButton = uibutton(app.ComparisonPanel, 'push');
            app.PSNRButton.ButtonPushedFcn = createCallbackFcn(app, @PSNRButtonPushed, true);
            app.PSNRButton.Enable = 'off';
            app.PSNRButton.Text = 'PSNR';
            app.WPSNRButton = uibutton(app.ComparisonPanel, 'push');
            app.WPSNRButton.ButtonPushedFcn = createCallbackFcn(app, @WPSNRButtonPushed, true);
            app.WPSNRButton.Enable = 'off';
            app.WPSNRButton.Text = 'WPSNR';
            app.SSIMButton = uibutton(app.ComparisonPanel, 'push');
            app.SSIMButton.ButtonPushedFcn = createCallbackFcn(app, @SSIMButtonPushed, true);
            app.SSIMButton.Enable = 'off';
            app.SSIMButton.Text = 'SSIM';
            app.NCButton = uibutton(app.ComparisonPanel, 'push');
            app.NCButton.ButtonPushedFcn = createCallbackFcn(app, @NCButtonPushed, true);
            app.NCButton.Enable = 'off';
            app.NCButton.Text = 'NC';
            app.VisualButton = uibutton(app.ComparisonPanel, 'push');
            app.VisualButton.ButtonPushedFcn = createCallbackFcn(app, @VisualButtonPushed, true);
            app.VisualButton.Enable = 'off';
            app.VisualButton.Text = 'Visual';
            app.HistogramButton = uibutton(app.ComparisonPanel, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Enable = 'off';
            app.HistogramButton.Text = 'Histogram';
            app.ExtractWatermarkTab = uitab(app.TabGroup);
            app.ExtractWatermarkTab.Title = 'Extract Watermark';
            app.ExtractWatermarkLabel = uilabel(app.ExtractWatermarkTab);
            app.ExtractWatermarkLabel.Text = 'Extract Watermark';
            app.DisplayPanel_2 = uipanel(app.ExtractWatermarkTab);
            app.DisplayPanel_2.Title = 'Display';
            app.UIAxes_3 = uiaxes(app.DisplayPanel_2);
            title(app.UIAxes_3, 'Watermarked Image')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.DisplayPanel_2);
            title(app.UIAxes_4, 'Extracted Watermark Image')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.Label_11 = uilabel(app.DisplayPanel_2);
            app.Label_11.Visible = 'off';
            app.Label_12 = uilabel(app.DisplayPanel_2);
            app.Label_12.Visible = 'off';
            app.ActionPanel_2 = uipanel(app.ExtractWatermarkTab);
            app.ActionPanel_2.Title = 'Action';
            app.ChooseWatermarkedImageButton = uibutton(app.ActionPanel_2, 'push');
            app.ChooseWatermarkedImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseWatermarkedImageButtonPushed, true);
            app.ChooseWatermarkedImageButton.Text = {'Choose Watermarked'; ' Image'};
            app.ChooseKeyButton_2 = uibutton(app.ActionPanel_2, 'push');
            app.ChooseKeyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChooseKeyButton_2Pushed, true);
            app.ChooseKeyButton_2.Enable = 'off';
            app.ChooseKeyButton_2.Text = 'Choose Key';
            app.ExtractWatermarkButton = uibutton(app.ActionPanel_2, 'push');
            app.ExtractWatermarkButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractWatermarkButtonPushed, true);
            app.ExtractWatermarkButton.Enable = 'off';
            app.ExtractWatermarkButton.Text = 'Extract Watermark';
            app.SaveExtractedWatermarkImageButton = uibutton(app.ActionPanel_2, 'push');
            app.SaveExtractedWatermarkImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExtractedWatermarkImageButtonPushed, true);
            app.SaveExtractedWatermarkImageButton.Enable = 'off';
            app.SaveExtractedWatermarkImageButton.Text = {'Save Extracted'; 'Watermark Image'};
            app.ChooseOriginalWatermarkImageButton = uibutton(app.ActionPanel_2, 'push');
            app.ChooseOriginalWatermarkImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseOriginalWatermarkImageButtonPushed, true);
            app.ChooseOriginalWatermarkImageButton.Enable = 'off';
            app.ChooseOriginalWatermarkImageButton.Text = {'Choose Original'; 'Watermark Image'};
            app.ComparisonPanel_2 = uipanel(app.ExtractWatermarkTab);
            app.ComparisonPanel_2.Title = 'Comparison';
            app.PSNRButton_2 = uibutton(app.ComparisonPanel_2, 'push');
            app.PSNRButton_2.ButtonPushedFcn = createCallbackFcn(app, @PSNRButton_2Pushed, true);
            app.PSNRButton_2.Enable = 'off';
            app.PSNRButton_2.Text = 'PSNR';
            app.WPSNRButton_2 = uibutton(app.ComparisonPanel_2, 'push');
            app.WPSNRButton_2.ButtonPushedFcn = createCallbackFcn(app, @WPSNRButton_2Pushed, true);
            app.WPSNRButton_2.Enable = 'off';
            app.WPSNRButton_2.Text = 'WPSNR';
            app.SSIMButton_2 = uibutton(app.ComparisonPanel_2, 'push');
            app.SSIMButton_2.ButtonPushedFcn = createCallbackFcn(app, @SSIMButton_2Pushed, true);
            app.SSIMButton_2.Enable = 'off';
            app.SSIMButton_2.Text = 'SSIM';
            app.NCButton_2 = uibutton(app.ComparisonPanel_2, 'push');
            app.NCButton_2.ButtonPushedFcn = createCallbackFcn(app, @NCButton_2Pushed, true);
            app.NCButton_2.Enable = 'off';
            app.NCButton_2.Text = 'NC';
            app.VisualButton_2 = uibutton(app.ComparisonPanel_2, 'push');
            app.VisualButton_2.ButtonPushedFcn = createCallbackFcn(app, @VisualButton_2Pushed, true);
            app.VisualButton_2.Enable = 'off';
            app.VisualButton_2.Text = 'Visual';
            app.HistogramButton_2 = uibutton(app.ComparisonPanel_2, 'push');
            app.HistogramButton_2.ButtonPushedFcn = createCallbackFcn(app, @HistogramButton_2Pushed, true);
            app.HistogramButton_2.Enable = 'off';
            app.HistogramButton_2.Text = 'Histogram';
            app.AttackandExtractTab = uitab(app.TabGroup);
            app.AttackandExtractTab.Title = 'Attack and Extract ';
            app.AttackandExtractLabel = uilabel(app.AttackandExtractTab);
            app.AttackandExtractLabel.Text = 'Attack and Extract ';
            app.DisplayPanel_3 = uipanel(app.AttackandExtractTab);
            app.DisplayPanel_3.Title = 'Display';
            app.UIAxes_5 = uiaxes(app.DisplayPanel_3);
            title(app.UIAxes_5, 'Attacked Image #1')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_6 = uiaxes(app.DisplayPanel_3);
            title(app.UIAxes_6, 'Extracted Watermark #1')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.Label = uilabel(app.DisplayPanel_3);
            app.Label_2 = uilabel(app.DisplayPanel_3);
            app.UIAxes_7 = uiaxes(app.DisplayPanel_3);
            title(app.UIAxes_7, 'Attacked Image #2')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.UIAxes_8 = uiaxes(app.DisplayPanel_3);
            title(app.UIAxes_8, 'Extracted Watermark #2')
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.Label_3 = uilabel(app.DisplayPanel_3);
            app.Label_4 = uilabel(app.DisplayPanel_3);
            app.UIAxes_9 = uiaxes(app.DisplayPanel_3);
            title(app.UIAxes_9, 'Attacked Image #3')
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.UIAxes_10 = uiaxes(app.DisplayPanel_3);
            title(app.UIAxes_10, 'Extracted Watermark #3')
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [];
            app.Label_5 = uilabel(app.DisplayPanel_3);
            app.Label_6 = uilabel(app.DisplayPanel_3);
            app.UIAxes_11 = uiaxes(app.DisplayPanel_3);
            title(app.UIAxes_11, 'Attacked Image #4')
            app.UIAxes_11.XTick = [];
            app.UIAxes_11.YTick = [];
            app.UIAxes_12 = uiaxes(app.DisplayPanel_3);
            title(app.UIAxes_12, 'Extracted Watermark #4')
            app.UIAxes_12.XTick = [];
            app.UIAxes_12.YTick = [];
            app.Label_7 = uilabel(app.DisplayPanel_3);
            app.Label_8 = uilabel(app.DisplayPanel_3);
            app.ListBoxLabel = uilabel(app.AttackandExtractTab);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.AttackandExtractTab);
            app.ChooseWatermarkedImageButton_2 = uibutton(app.AttackandExtractTab, 'push');
            app.ChooseWatermarkedImageButton_2.Text = {'Choose Watermarked'; ' Image'};
            app.ChooseWatermarkImageToCompareButton = uibutton(app.AttackandExtractTab, 'push');
            app.ChooseWatermarkImageToCompareButton.Text = {'Choose Watermark'; 'Image To Compare'};
            app.ComparisonTab = uitab(app.TabGroup);
            app.ComparisonTab.Title = 'Comparison';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MainGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WatermarkUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WatermarkUIFigure)
        end
    end
end